classdef GnssProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModeDropDownLabel          matlab.ui.control.Label
        ModeDropDown               matlab.ui.control.DropDown
        DDMDopplersEditFieldLabel  matlab.ui.control.Label
        DDMDopplersEditField       matlab.ui.control.NumericEditField
        DopplerResEditFieldLabel   matlab.ui.control.Label
        DopplerResEditField        matlab.ui.control.NumericEditField
        MaxAvoidEditFieldLabel     matlab.ui.control.Label
        MaxAvoidEditField          matlab.ui.control.NumericEditField
        STDRangeEditFieldLabel     matlab.ui.control.Label
        STDRangeEditField          matlab.ui.control.NumericEditField
        StoSaveEditFieldLabel      matlab.ui.control.Label
        StoSaveEditField           matlab.ui.control.NumericEditField
        IncoherentsEditFieldLabel  matlab.ui.control.Label
        IncoherentsEditField       matlab.ui.control.NumericEditField
        FFTEditFieldLabel          matlab.ui.control.Label
        FFTEditField               matlab.ui.control.NumericEditField
        DatalinesLabel             matlab.ui.control.Label
        DatalinesEditField         matlab.ui.control.NumericEditField
        GNSSPROCESSINGLabel        matlab.ui.control.Label
        SetresultspathButton       matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        BlockSizeEditFieldLabel    matlab.ui.control.Label
        BlockSizeEditField         matlab.ui.control.NumericEditField
        UITable                    matlab.ui.control.Table
        RefFilenameEditFieldLabel  matlab.ui.control.Label
        RefFilenameEditField       matlab.ui.control.EditField
        OverlapEditFieldLabel      matlab.ui.control.Label
        OverlapEditField           matlab.ui.control.NumericEditField
        FFTsizeEditFieldLabel      matlab.ui.control.Label
        FFTsizeEditField           matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: ModeDropDown
        function beamValue(app, event)
            value = app.ModeDropDown.Value;
             if(strcmp(value,'Conventional'))  
               app.RefFilenameEditFieldLabel.Enable = 'on';
               app.RefFilenameEditField.Enable = 'on';
               
               
               app.UITable.ColumnName = {'Data Filename'; 'Offset Beg'; 'Offset End'; 'Doppler'};
               app.UITable.ColumnFormat={'char','numeric','numeric','numeric','numeric'};
             elseif(strcmp(value,'Interferometric'))
               app.RefFilenameEditFieldLabel.Enable = 'off';
               app.RefFilenameEditField.Enable = 'off';
               
               
               app.UITable.ColumnName = {'Data Filename'; 'Offset Beg'; 'Offset End'; 'Doppler'; 'Ref. Filename'; 'Ref. Offset' };
               app.UITable.ColumnFormat={'char','numeric','numeric','numeric','char','numeric'};
             elseif(strcmp(value,'-'))
               app.RefFilenameEditFieldLabel.Enable = 'off';
               app.RefFilenameEditField.Enable = 'off';
               app.UITable.Enable = 'off';
            end
        end

        % Value changed function: DDMDopplersEditField
        function DDMRes2(app, event)
            value = app.DDMDopplersEditField.Value;
            if(mod(value,2)==0)
            app.DDMDopplersEditField.Value = app.DDMDopplersEditField.Value - 1;
                
            end
            
            if(value>1)
                 app.DopplerResEditFieldLabel.Enable = 'on';
                 app.DopplerResEditField.Enable = 'on';
            else
                 app.DopplerResEditFieldLabel.Enable = 'off';
                 app.DopplerResEditField.Enable = 'off';
            end
            
            
        end

        % Value changed function: STDRangeEditField
        function STDodd(app, event)
            value = app.STDRangeEditField.Value;
            if(mod(value,2)==0)
              app.STDRangeEditField.Value = app.STDRangeEditField.Value - 1;
                
            end
        end

        % Value changed function: StoSaveEditField
        function SSaveOdd(app, event)
            value = app.StoSaveEditField.Value;
            if(mod(value,2)==0)
              app.StoSaveEditField.Value = app.StoSaveEditField.Value - 1;
                
            end
            
        end

        % Callback function
        function FFTRestriction(app, event)
            value = app.FFTDropDown.Value;
            
            app.StoSaveEditField.Limits = [1 str2num(value)];
            app.STDRangeEditField.Limits = [1 str2num(value)];
            app.MaxAvoidEditField.Limits = [0 str2num(value)];
            
            
        end

        % Value changed function: DatalinesEditField
        function LastSample2(app, event)
            value = app.DatalinesEditField.Value;
           
            if(value~=0)
            app.UITable.Enable = 'on';
            app.UITable.RowName = {1:value};
            app.UITable.Data=cell(value,length(app.UITable.ColumnName));
            app.Label.Text = 'If you update # Datalines all entries will be lost';
            end
           
           
        end

        % Button pushed function: SetresultspathButton
        function ResultPath3(app, event)
            %
            datalines= app.UITable.Data;
            %
            path=uigetdir();
            
            app.Label_2.Text = path;
            
            
            fileID = fopen(strcat(path,'\input.ASE'),'w');
          
            fprintf(fileID,'*FFTSIZE %d\n', app.FFTsizeEditField.Value);
            fprintf(fileID,'*NUMOFFFTS %d\n', app.FFTEditField.Value);
            fprintf(fileID,'*QUANTINCOHAVER %d\n', app.IncoherentsEditField.Value);           

            fprintf(fileID,'*OVERLAP %d\n', app.OverlapEditField.Value);
                                   
            fprintf(fileID,'*FSAMPLING %d\n', 32736000);%sempre aixi?
            fprintf(fileID,'*BLOCKSIZE %d\n', app.BlockSizeEditField.Value);         
            if(strcmp(app.ModeDropDown.Value,'Conventional')) 
                fprintf(fileID,'*INTERFEROMETIC %d\n', 0);      
            elseif(strcmp(app.ModeDropDown.Value,'Interferometric'))
                fprintf(fileID,'*INTERFEROMETIC %d\n', 1);
            else
                fclose(fileID);
                app.Label.Text = 'Warning:';
                app.Label_2.Text='Mode not set, set propper options';
                app.RunButton.Enable = 'off';
                return;
            end
            fprintf(fileID,'*SAMPLESAVOIDMAX %d\n', app.MaxAvoidEditField.Value);            
            fprintf(fileID,'*PEAKRANGESTD %d\n', app.STDRangeEditField.Value);                       
            fprintf(fileID,'*PEAKSAMPLESTOSAVE  %d\n', app.StoSaveEditField.Value);         
            fprintf(fileID,'*REFFILENAME %s\n', app.RefFilenameEditField.Value);
            fprintf(fileID,'*RESULTSDIRECTORY %s\n', path);            
            fprintf(fileID,'*DDMFREQRES %d\n', app.DopplerResEditField.Value);
            fprintf(fileID,'*DDMNUMQUANT %d\n', app.DDMDopplersEditField.Value);           
            fprintf(fileID,'*QUANTDATALINES %d\n',app.DatalinesEditField.Value);%FALTA
            %DATALINES
            if(strcmp(app.ModeDropDown.Value,'Conventional'))
                for i=1:app.DatalinesEditField.Value
                    fprintf(fileID,'*DATALINE %s %d %d %d\n',datalines{i,:});
                end
                
            else
                for i=1:app.DatalinesEditField.Value
                    fprintf(fileID,'*DATALINE %s %d %d %d %s %d\n',datalines{i,:});
                end
            end
            
            
            fclose(fileID);
            app.RunButton.Enable = 'on';
            
        end

        % Button pushed function: RunButton
        function runprogram(app, event)
            configPath=strcat(app.Label_2.Text,'\input.ASE');            
            comandLine=strcat(".\..\bin\gnssprocessgpu ",{' '},configPath,{' '},app.DatalinesEditField.Value);
            [status,cmdout ] = system(comandLine);                        
            if(status ~= 0)
               app.Label_2.Text='Something went wrong, look Matlab comandline for +info.';                 
            else
               app.Label_2.Text ="Finished!, look Matlab comandline for +info."
            end
            fprintf(cmdout);
            app.RunButton.Enable = 'off';
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'-', 'Conventional', 'Interferometric'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @beamValue, true);
            app.ModeDropDown.Value = '-';
            app.DDMDopplersEditFieldLabel = uilabel(app.UIFigure);
            app.DDMDopplersEditFieldLabel.Tooltip = {'Quantity of Dopplers for DDM'; ''; '1 for no DDM compute'};
            app.DDMDopplersEditFieldLabel.Text = 'DDM #Dopplers';
            app.DDMDopplersEditField = uieditfield(app.UIFigure, 'numeric');
            app.DDMDopplersEditField.Limits = [1 Inf];
            app.DDMDopplersEditField.RoundFractionalValues = 'on';
            app.DDMDopplersEditField.ValueChangedFcn = createCallbackFcn(app, @DDMRes2, true);
            app.DDMDopplersEditField.Tooltip = {'Must be an odd number'};
            app.DDMDopplersEditField.Value = 1;
            app.DopplerResEditFieldLabel = uilabel(app.UIFigure);
            app.DopplerResEditFieldLabel.Enable = 'off';
            app.DopplerResEditFieldLabel.Tooltip = {'Resolution between 2 consecutive dopplers in a DDM'};
            app.DopplerResEditFieldLabel.Text = 'Doppler Res';
            app.DopplerResEditField = uieditfield(app.UIFigure, 'numeric');
            app.DopplerResEditField.Limits = [1 Inf];
            app.DopplerResEditField.RoundFractionalValues = 'on';
            app.DopplerResEditField.Enable = 'off';
            app.DopplerResEditField.Tooltip = {'Hz'};
            app.DopplerResEditField.Value = 1;
            app.MaxAvoidEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAvoidEditFieldLabel.Tooltip = {'Samples to avoid in the begining of the '; 'correlation to compute the Maximum'};
            app.MaxAvoidEditFieldLabel.Text = 'Max Avoid';
            app.MaxAvoidEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAvoidEditField.Limits = [0 32736];
            app.MaxAvoidEditField.RoundFractionalValues = 'on';
            app.MaxAvoidEditField.Tooltip = {'Samples to avoid in the begining of the '; 'correlation to compute the Maximum'};
            app.STDRangeEditFieldLabel = uilabel(app.UIFigure);
            app.STDRangeEditFieldLabel.Tooltip = {'Samples to avoid arround the peak to compute the STD and Mean'; 'The peak will be in the middle'};
            app.STDRangeEditFieldLabel.Text = 'STD Range';
            app.STDRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.STDRangeEditField.Limits = [1 32736];
            app.STDRangeEditField.RoundFractionalValues = 'on';
            app.STDRangeEditField.ValueChangedFcn = createCallbackFcn(app, @STDodd, true);
            app.STDRangeEditField.Tooltip = {'Odd number'};
            app.STDRangeEditField.Value = 1;
            app.StoSaveEditFieldLabel = uilabel(app.UIFigure);
            app.StoSaveEditFieldLabel.Tooltip = {'Samples to save arround the peak'; 'Peak in the middle'};
            app.StoSaveEditFieldLabel.Text = 'S. to Save';
            app.StoSaveEditField = uieditfield(app.UIFigure, 'numeric');
            app.StoSaveEditField.Limits = [1 32736];
            app.StoSaveEditField.RoundFractionalValues = 'on';
            app.StoSaveEditField.ValueChangedFcn = createCallbackFcn(app, @SSaveOdd, true);
            app.StoSaveEditField.Tooltip = {'Odd Number'};
            app.StoSaveEditField.Value = 1;
            app.IncoherentsEditFieldLabel = uilabel(app.UIFigure);
            app.IncoherentsEditFieldLabel.Tooltip = {'# of coherents to add for a incoherent'};
            app.IncoherentsEditFieldLabel.Text = 'Incoherents';
            app.IncoherentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncoherentsEditField.Limits = [1 Inf];
            app.IncoherentsEditField.RoundFractionalValues = 'on';
            app.IncoherentsEditField.Value = 1;
            app.FFTEditFieldLabel = uilabel(app.UIFigure);
            app.FFTEditFieldLabel.Tooltip = {'Number of FFT to compute simultaneously'; '(this will be mul. by #Dopplers)'};
            app.FFTEditFieldLabel.Text = '# FFT';
            app.FFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.FFTEditField.Limits = [1 Inf];
            app.FFTEditField.RoundFractionalValues = 'on';
            app.FFTEditField.Value = 1;
            app.DatalinesLabel = uilabel(app.UIFigure);
            app.DatalinesLabel.Text = '# Datalines';
            app.DatalinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatalinesEditField.Limits = [0 Inf];
            app.DatalinesEditField.RoundFractionalValues = 'on';
            app.DatalinesEditField.ValueChangedFcn = createCallbackFcn(app, @LastSample2, true);
            app.GNSSPROCESSINGLabel = uilabel(app.UIFigure);
            app.GNSSPROCESSINGLabel.Text = 'GNSS PROCESSING';
            app.SetresultspathButton = uibutton(app.UIFigure, 'push');
            app.SetresultspathButton.ButtonPushedFcn = createCallbackFcn(app, @ResultPath3, true);
            app.SetresultspathButton.Tooltip = {'Sets the path where results will be saved and creates the config file'};
            app.SetresultspathButton.Text = 'Set results path';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runprogram, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'To run first set result path'};
            app.RunButton.Text = 'Run';
            app.BlockSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlockSizeEditFieldLabel.Tooltip = {'Threads per block to make the computation'};
            app.BlockSizeEditFieldLabel.Text = 'BlockSize';
            app.BlockSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlockSizeEditField.Limits = [1 Inf];
            app.BlockSizeEditField.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Data Filename'; 'Offset Beg'; 'Offset End'; 'Doppler'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.Enable = 'off';
            app.RefFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.RefFilenameEditFieldLabel.Enable = 'off';
            app.RefFilenameEditFieldLabel.Text = 'Ref. Filename';
            app.RefFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.RefFilenameEditField.Enable = 'off';
            app.RefFilenameEditField.Value = 'C:\test\Reference.bin';
            app.OverlapEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.FFTsizeEditFieldLabel = uilabel(app.UIFigure);
            app.FFTsizeEditFieldLabel.Text = 'FFT size';
            app.FFTsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FFTsizeEditField.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = GnssProcessing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end